classdef Project_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        CenterPanel            matlab.ui.container.Panel
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
        RightPanel             matlab.ui.container.Panel
        SharpenButton          matlab.ui.control.Button
        SmoothButton           matlab.ui.control.Button
        GaussianButton         matlab.ui.control.Button
        BrightnessSlider       matlab.ui.control.Slider
        BrightnessSliderLabel  matlab.ui.control.Label
        SketchButton           matlab.ui.control.Button
        EdgesButton            matlab.ui.control.Button
        SaveImageButton        matlab.ui.control.Button
        LoadImageButton        matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        LoadedImage % Description
        currentImage % Description
    end
    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % --- Executes on button press in loadImageButton.

     % Open a file dialog to select an image
         % Open a file dialog to select an image
                [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.gif', 'Image Files'}, 'Select an Image File');
             if file
                % Load the image
                imagePath = fullfile(path, file);
                app.LoadedImage = imread(imagePath);
                app.currentImage = app.LoadedImage;
                doubleImage = double(app.LoadedImage) / 255;

                % Update the 'Image' property of the selected Image template button
                imshow(app.LoadedImage,'Parent',app.UIAxes);
                imshow(app.LoadedImage,'Parent',app.UIAxes2);
                % Flatten each color channel
                redChannel = reshape(doubleImage(:,:,1), [], 1);
                greenChannel = reshape(doubleImage(:,:,2), [], 1);
                blueChannel = reshape(doubleImage(:,:,3), [], 1);
                redHist = histcounts(redChannel, 0:1/256:1);
                greenHist = histcounts(greenChannel, 0:1/256:1);
                blueHist = histcounts(blueChannel, 0:1/256:1);
                plot(app.UIAxes3, 0:1/256:1-1/256, redHist, 'r');
                hold(app.UIAxes3, 'on');
                plot(app.UIAxes3, 0:1/256:1-1/256, greenHist, 'g');
                plot(app.UIAxes3, 0:1/256:1-1/256, blueHist, 'b');
                hold(app.UIAxes3, 'off');

             end
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            [file, path] = uiputfile({'*.jpg;*.png;*.bmp;*.gif', 'Image Files'}, 'Save Image');
              if file
                imwrite(app.currentImage, fullfile(path, file));
                 msgbox('Image saved successfully!', 'Success', 'modal');
              end
        end

        % Button down function: LeftPanel
        function LeftPanelButtonDown(app, event)
            
        end

        % Callback function
        function BrightnessSliderValueChanged(app, event)
         
        end

        % Button pushed function: SketchButton
        function SketchButtonPushed(app, event)
            kernel = [-1 -1 -1;-1 10 -1;-1 -1 -1];
            grayImage = rgb2gray(app.currentImage);
            newimage = convolverGray(grayImage,kernel);
            imshow(uint8(newimage),'Parent',app.UIAxes2);
            app.currentImage = newimage;
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged2(app, event)
            value = app.BrightnessSlider.Value;
            kernel = [0 0 0;0 value 0; 0 0 0]
            resultantImage = convolverRGB(app.currentImage,kernel);
            imshow(uint8(resultantImage),'Parent',app.UIAxes2);
            app.currentImage = resultantImage;
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
            % Load an example image (replace 'example_image.jpg' with the path to your image)
                % Load an example image (replace 'example_image.jpg' with the path to your image)


        end

        % Callback function
        function BlurSliderValueChanged(app, event)
            
        end

        % Button pushed function: GaussianButton
        function GaussianButtonPushed(app, event)
           kernel = [0.1625 0.025 0.1625;0.025 .15 0.125;0.1625 0.025 0.1625];
           img = app.currentImage;
           for x = 1:5;
               blurImage = convolverRGB(img,kernel);
               img = blurImage;
           end
           imshow(uint8(img),'Parent',app.UIAxes2);
        end

        % Button pushed function: SmoothButton
        function SmoothButtonPushed(app, event)
            kernel = [0.1625 0.025 0.1625;0.025 .15 0.125;0.1625 0.025 0.1625];
            img = app.currentImage;
            blurImage = convolverRGB(img,kernel);
            imshow(uint8(blurImage),'Parent',app.UIAxes2);
        end

        % Button pushed function: EdgesButton
        function EdgesButtonPushed(app, event)
            kernel = [0 -1 0;-1 4.1 -1;0 -1 0];
            edged = convolverRGB(app.currentImage,kernel);
            imshow(uint8(edged),'Parent',app.UIAxes2);
        end

        % Button pushed function: SharpenButton
        function SharpenButtonPushed(app, event)
            kernel = [-1 0 0;0 3 0;0 0 -1];
            sharped = convolverRGB(app.currentImage,kernel);
            imshow(uint8(sharped),'Parent',app.UIAxes2);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x', 252};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.ButtonDownFcn = createCallbackFcn(app, @LeftPanelButtonDown, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Original')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.CenterPanel);
            title(app.UIAxes2, 'Editor')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.CenterPanel);
            title(app.UIAxes3, 'RGB Color Spectrum')
            xlabel(app.UIAxes3, 'Pixel Values')
            ylabel(app.UIAxes3, 'Frequency')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XLim = [0 1];
            app.UIAxes3.YLim = [0 100000];
            app.UIAxes3.XTick = [0 0.5 1];
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.LoadImageButton = uibutton(app.RightPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SaveImageButton = uibutton(app.RightPanel, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.EdgesButton = uibutton(app.RightPanel, 'push');
            app.EdgesButton.ButtonPushedFcn = createCallbackFcn(app, @EdgesButtonPushed, true);
            app.EdgesButton.Text = 'Edges ';
            app.SketchButton = uibutton(app.RightPanel, 'push');
            app.SketchButton.ButtonPushedFcn = createCallbackFcn(app, @SketchButtonPushed, true);
            app.SketchButton.Text = 'Sketch';
            app.BrightnessSliderLabel = uilabel(app.RightPanel);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.RightPanel);
            app.BrightnessSlider.Limits = [0.1 1.9];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged2, true);
            app.BrightnessSlider.Value = 1;
            app.GaussianButton = uibutton(app.RightPanel, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.GaussianButton.Text = 'Gaussian';
            app.SmoothButton = uibutton(app.RightPanel, 'push');
            app.SmoothButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothButtonPushed, true);
            app.SmoothButton.Text = 'Smooth';
            app.SharpenButton = uibutton(app.RightPanel, 'push');
            app.SharpenButton.ButtonPushedFcn = createCallbackFcn(app, @SharpenButtonPushed, true);
            app.SharpenButton.Text = 'Sharpen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end